classdef CV_challenge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AboutMenu                     matlab.ui.container.Menu
        detectcameraParButton         matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        ReadyLamp_3                   matlab.ui.control.Lamp
        ReadyLamp_3Label              matlab.ui.control.Label
        ReadyLamp_2                   matlab.ui.control.Lamp
        ReadyLamp_2Label              matlab.ui.control.Label
        ReadyLamp                     matlab.ui.control.Lamp
        ReadyLampLabel                matlab.ui.control.Label
        imageSizeEditField_2          matlab.ui.control.NumericEditField
        principalPointEditField_2     matlab.ui.control.NumericEditField
        focalLengthEditField_2        matlab.ui.control.NumericEditField
        imageSizeEditField            matlab.ui.control.NumericEditField
        imageSizeEditFieldLabel       matlab.ui.control.Label
        principalPointEditField       matlab.ui.control.NumericEditField
        principalPointEditFieldLabel  matlab.ui.control.Label
        focalLengthEditField          matlab.ui.control.NumericEditField
        focalLengthEditFieldLabel     matlab.ui.control.Label
        ConfirmButton                 matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        SelectImagesButton            matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        images
        intrinsics
        imds
        SelectImagesButtonPressed = false;
        ConfirmButtonPressed = false;
        detectcameraParButtonPressed = false;
        opts = struct('WindowStyle','modal',... 
              'Interpreter','tex');
        opts1 = struct('Default','Cancel',... 
              'Interpreter','tex');
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImagesButton
        function SelectImagesButtonPushed(app, event)
            [folderPath] = uigetdir('','Select a folder');
            if isequal(folderPath,0)
                errordlg("\fontsize{16}No images selected","error",app.opts);
                return;
            else
                imageDir = fullfile(folderPath);
                app.imds = imageDatastore(imageDir);
            end
            app.ReadyLamp_2.Color = "0.00,1.00,0.00";
            app.SelectImagesButtonPressed = true;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Initialization
            % lamp
            if ~app.SelectImagesButtonPressed
                errordlg("\fontsize{16}Please select images first.","error",app.opts);
                return;
            elseif ~app.ConfirmButtonPressed
                errordlg("\fontsize{16}Please comfirm the parameters of the camera.","error",app.opts);
                return;
            end
                        
            % waitbar init
            waitBar1 = waitbar(0,'Preparing...','Name','Reconstruction');

            % variable
            app.images = cell(1, numel(app.imds.Files));
            for i = 1:numel(app.imds.Files)
                I = readimage(app.imds, i);
                app.images{i}= medfilt2(im2gray(I));
            end
            num_img =numel(app.imds.Files);
            Points =cell(1, num_img);
            Features=cell(1, num_img);
            Points{1}   = detectSIFTFeatures(app.images{1});
            [Features{1}, Points{1}]= extractFeatures(app.images{1}, Points{1});
            
            %% establish vSet。
            vSet = imageviewset;
            viewId = 1;
            vSet = addView(vSet, viewId, rigidtform3d, Points=Points{1});
            
            %% core
            %test =4;
            %tstart = tic;
            for i =  2:num_img
                % waitbar
                pause(0.01)
                %tnow = toc(tstart);
                %trem = tnow/i*(num_img-i);
                % tips = {['Progress:',num2str(i),'/',num2str(num_img)];
                %     ['Remaining Time:',num2str(round(trem,1))]};
                tips = ['Progress:',num2str(i),'/',num2str(num_img)];
                waitbar(i/num_img,waitBar1,tips);

                % compute
                Points{i}= detectSIFTFeatures(app.images{i});
                [Features{i}, Points{i}]= extractFeatures(app.images{i}, Points{i});

                max_Pairs=0;
                for j =1:i-1
                    indexPairs  = matchFeatures(Features{i-j}, Features{i});                
                    if numel(indexPairs) >max_Pairs
                        max_Pairs=numel(indexPairs);
                        k=j;
                        indexPairs_k=indexPairs;
                    end
                end
                
                matchedPoints1 = Points{i-k}(indexPairs_k(:, 1));
                matchedPoints2 = Points{i}(indexPairs_k(:, 2));
            
                [E, inlierIdx] = estimateEssentialMatrix(matchedPoints1.Location, matchedPoints2.Location,...
                    app.intrinsics);  
                inlierPoints1 = matchedPoints1.Location(inlierIdx, :);
                inlierPoints2 = matchedPoints2.Location(inlierIdx, :);        
                [relPose, validPointFraction] = estrelpose(E, app.intrinsics, inlierPoints1(1:2:end, :),...
                inlierPoints2(1:2:end, :));          
                prevPose = poses(vSet, i-k).AbsolutePose;
                currPose = rigidtform3d(prevPose.A*relPose(1).A);      
                vSet = addView(vSet, i, currPose, Points=Points{i});  
                vSet = addConnection(vSet, i-k, i, relPose, Matches=indexPairs_k(inlierIdx,:));         
                tracks = findTracks(vSet);    
                camPoses = poses(vSet);     
                xyzPoints = triangulateMultiview(tracks, camPoses, app.intrinsics);     
                [xyzPoints, camPoses, reprojectionErrors] = bundleAdjustment(xyzPoints, ...
                    tracks, camPoses, app.intrinsics, FixedViewId=1, ...
                    PointsUndistorted=true);      
                vSet = updateView(vSet, camPoses);
            end
            %toc
            close(waitBar1);
            
            %% show
            axesHandle = app.UIAxes;

            %% xyzPoints
            % Exclude noisy 3-D points.
            goodIdx = (reprojectionErrors < 5);
            xyzPoints = xyzPoints(goodIdx, :);
            xyzPoints1 = xyzPoints';
            sz=10;
            scatter3(axesHandle,xyzPoints1(1,:),xyzPoints1(2,:),xyzPoints1(3,:),sz,'filled');
            grid(axesHandle,"on") 
            hold(axesHandle,"on") 
            app.ReadyLamp_3.Color = "0.00,1.00,0.00";

            %% 3D
            % pointCloud with colour
            ptCloud = pointCloud(xyzPoints);
            distanceThreshold = 1.0; 
            numClusterPoints = 100;     
            labels = pcsegdist(ptCloud, distanceThreshold, 'NumClusterPoints', numClusterPoints);
            figure;
            % subplot(1,2,1);
            segmentedClouds = cell(max(labels),1);
            for i = 1:max(labels)
                segmentedClouds{i} = select(ptCloud, labels == i);
            end            
            for i = 1:numel(segmentedClouds)
                pcshow(segmentedClouds{i});
                hold(axesHandle,"on")
            end
            hold(axesHandle,"off")

            % 3D-model
            minNumPointsForAlphaShape = 4;      
            colors = jet(numel(segmentedClouds));       
            % subplot(1,2,2);
            figure;
            for i = 1:numel(segmentedClouds)
                xyzPointsCurrent = double(segmentedClouds{i}.Location);
                if size(xyzPointsCurrent, 1) < minNumPointsForAlphaShape
                    continue;
                end    
                shp = alphaShape(xyzPointsCurrent(:,1), xyzPointsCurrent(:,2), xyzPointsCurrent(:,3));                
                plot(shp, 'FaceColor', colors(i, :), 'EdgeColor', 'k');
                hold(axesHandle,"on")
            end
            hold(axesHandle,"off")

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if ~app.detectcameraParButtonPressed
                errordlg("\fontsize{16}Please detect the parameters of the camera first.","error",app.opts);
                return;
            end
            f1=app.focalLengthEditField.Value;
            f2=app.focalLengthEditField_2.Value;
            p1=app.principalPointEditField.Value;
            p2=app.principalPointEditField_2.Value;
            im1=app.imageSizeEditField.Value;
            im2=app.imageSizeEditField_2.Value;
            if isequal(f1,0) && isequal(f2,0)
               focalLength = [3408.59,3408.87];
            else
               focalLength = [f1,f2];
            end
            if isequal(p1,0) && isequal(p2,0)
                principalPoint =[3117.24,2064.07];
            else
                principalPoint = [p1,p2];
            end
            if isequal(im1,0) && isequal(im2,0)
                imageSize = size(app.images{1});
            else
                imageSize = [im1,im2];
            end
            app.intrinsics = cameraIntrinsics(focalLength,principalPoint,imageSize);
            app.ReadyLamp.Color = "0.00,1.00,0.00";
            app.ConfirmButtonPressed = true;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            choice = questdlg('\fontsize{16}Do you want to close?','Close','Close','Cancel',app.opts1);
            switch choice
                case 'Close'
                    delete(app.UIFigure);
                    return;
                case 'Cancel'
                    return;
            end
        end

        % Button pushed function: ResetButton
        function ResetMenuSelected(app, event)
            choice = questdlg('\fontsize{16}Do you want to reset?','Reset','Reset','Cancel',app.opts1);
            switch choice
                case 'Reset'
                    app.ReadyLamp.Color = "1.00,0.00,0.00";
                    app.ReadyLamp_2.Color = "1.00,0.00,0.00";
                    app.ReadyLamp_3.Color = "1.00,0.00,0.00";
                    cla(app.UIAxes);
                    app.focalLengthEditField.Value = 0;
                    app.focalLengthEditField_2.Value = 0;
                    app.principalPointEditField.Value = 0;
                    app.principalPointEditField_2.Value = 0;
                    app.imageSizeEditField.Value = 0;
                    app.imageSizeEditField_2.Value = 0;
                    app.SelectImagesButtonPressed = false;
                    app.ConfirmButtonPressed = false;
                    app.detectcameraParButtonPressed = false;
                    return;
                case 'Cancel'
                    return;
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox("\fontsize{16}This is a demo.","About this application",app.opts)
        end

        % Button pushed function: detectcameraParButton
        function detectcameraParButtonPushed(app, event)
            folder = uigetdir('select the folder');
            if isequal(folder,0)
                errordlg("\fontsize{16}No folder selected","error",app.opts);
                return;
            end
            fileID = fullfile(folder, 'cameras.txt');
            if exist(fileID,'file') == 0
                msgbox("\fontsize{16}The camera parameters were not found in the current folder, please enter them manually.","error",app.opts)
                return;
            else
                camPar = readtable(fileID);
                camPar = table2array(camPar(2,3:8));
                app.focalLengthEditField.Value = camPar(1,3);
                app.focalLengthEditField_2.Value =camPar(1,4);
                app.principalPointEditField.Value = camPar(1,5);
                app.principalPointEditField_2.Value = camPar(1,6);
                app.imageSizeEditField.Value = camPar(1,2);
                app.imageSizeEditField_2.Value = camPar(1,1);
                msgbox("\fontsize{16}Camera parameters are entered.","Hint",app.opts)
            end
            app.detectcameraParButtonPressed = true;
        end

        % Callback function
        function NocameratxtSwitchValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D Pointcloud')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SelectImagesButton = uibutton(app.UIFigure, 'push');
            app.SelectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImagesButtonPushed, true);
            app.SelectImagesButton.Text = 'Select Images';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.focalLengthEditFieldLabel = uilabel(app.UIFigure);
            app.focalLengthEditFieldLabel.Text = 'focalLength';
            app.focalLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.focalLengthEditField.ValueDisplayFormat = '%.2f';
            app.principalPointEditFieldLabel = uilabel(app.UIFigure);
            app.principalPointEditFieldLabel.Text = 'principalPoint';
            app.principalPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.principalPointEditField.ValueDisplayFormat = '%.2f';
            app.imageSizeEditFieldLabel = uilabel(app.UIFigure);
            app.imageSizeEditFieldLabel.Text = 'imageSize';
            app.imageSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.imageSizeEditField.ValueDisplayFormat = '%.2f';
            app.focalLengthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.focalLengthEditField_2.ValueDisplayFormat = '%.2f';
            app.principalPointEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.principalPointEditField_2.ValueDisplayFormat = '%.2f';
            app.imageSizeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.imageSizeEditField_2.ValueDisplayFormat = '%.2f';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.ReadyLamp_2Label = uilabel(app.UIFigure);
            app.ReadyLamp_2Label.Text = 'Ready';
            app.ReadyLamp_2 = uilamp(app.UIFigure);
            app.ReadyLamp_3Label = uilabel(app.UIFigure);
            app.ReadyLamp_3Label.Text = 'Ready';
            app.ReadyLamp_3 = uilamp(app.UIFigure);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetButton.Text = 'Reset';
            app.detectcameraParButton = uibutton(app.UIFigure, 'push');
            app.detectcameraParButton.ButtonPushedFcn = createCallbackFcn(app, @detectcameraParButtonPushed, true);
            app.detectcameraParButton.Text = 'detect cameraPar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CV_challenge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end